classdef Cell_counter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InstructionsTextArea       matlab.ui.control.TextArea
        InstructionsTextAreaLabel  matlab.ui.control.Label
        CellCountTextAreaLabel     matlab.ui.control.Label
        CellCountTextArea          matlab.ui.control.TextArea
        ImportimageButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportimageButton
        function ImportimageButtonPushed(app, event)
                         % Open file selection dialog to select an image
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tiff', 'Image Files'}, 'Select an Image');
            
            if file ~= 0  % If user did not cancel the selection
                % Construct full file path
                imgPath = fullfile(path, file);
                
                % Read the image
                img = imread(imgPath);
                
                % Display the image in the Image UI component
                app.Image.ImageSource = imgPath;
                
                % Optional: Display the file name in the TextArea
                [~, imgName, ext] = fileparts(imgPath);
                app.CellCountTextArea.Value = {['File: ' imgName ext]};
                
                % Load the model and parameters
                loadedData = load("E:\BME Stuffs\BME Resources\3-2 resources\DSP project\Final_pipeline\Final_iteration_pipeline\finalModel2.mat");
                finalModelLoaded = loadedData.modelStruct.model;  % Access the loaded model
                mu = loadedData.modelStruct.mu;
                sigma = loadedData.modelStruct.sigma;
                Ravg = loadedData.modelStruct.Ravg;
                Gavg = loadedData.modelStruct.Gavg;
                Bavg = loadedData.modelStruct.Bavg;
                disp('Model loaded successfully!');
                
                % Preprocess and extract features
                Features = preprocess_and_extract_features(imgPath, Ravg, Gavg, Bavg);
                X = Features{:,:};
                X_scaled = (X - mu) ./ sigma;  % Normalize features
                
                % Predict using the model
                y_pred = predict(finalModelLoaded, X_scaled);
                y_ans = str2num(cell2mat(y_pred));  % Convert predictions to numeric values
                
                % Calculate counts
                totalSum = sum(y_ans);  % Sum of all predicted values
                two_cell_count = sum(y_ans == 2);
                three_cell_count = sum(y_ans == 3);
                four_cell_count = sum(y_ans == 4);
                
                % Display the results in the TextArea
                resultText = {
                    ['Total Overlapped Cells: ', num2str(two_cell_count+three_cell_count+four_cell_count)],
                    ['Two Cells: ', num2str(two_cell_count)],
                    ['Three Cells: ', num2str(three_cell_count)],
                    ['Four Cells: ', num2str(four_cell_count)],
                    ['Total Count: ', num2str(totalSum)]
                };
                app.CellCountTextArea.Value = resultText;  % Display in the TextArea
            end
            
            % Helper Functions
            function normalized_img = normalize_image(img, Ravg, Gavg, Bavg)
                I = double(img);
                normalized_img = zeros(480, 640, 3);

                ravg = mean(I(:,:,1), "all");
                gavg = mean(I(:,:,2), "all");
                bavg = mean(I(:,:,3), "all");

                normalized_img(:,:,1) = I(:,:,1) + (Ravg - ravg);
                normalized_img(:,:,2) = I(:,:,2) + (Gavg - gavg);
                normalized_img(:,:,3) = I(:,:,3) + (Bavg - bavg);

                normalized_img = uint8(normalized_img);
            end

            function [BW, maskedRGBImage] = createMask(RGB)
                % Convert RGB image to chosen color space
                I = rgb2hsv(RGB);

                % Define thresholds for each channel in HSV color space
                channel1Min = 0.525;
                channel1Max = 0.000;
                channel2Min = 0.073;
                channel2Max = 0.279;
                channel3Min = 0.000;
                channel3Max = 1.000;

                % Create mask based on chosen histogram thresholds
                sliderBW = ( (I(:,:,1) >= channel1Min) | (I(:,:,1) <= channel1Max) ) & ...
                    (I(:,:,2) >= channel2Min) & (I(:,:,2) <= channel2Max) & ...
                    (I(:,:,3) >= channel3Min) & (I(:,:,3) <= channel3Max);
                BW = sliderBW;

                % Initialize output masked image based on input image.
                maskedRGBImage = RGB;
                maskedRGBImage(repmat(~BW, [1 1 3])) = 0;
            end

            function [features] = preprocess_and_extract_features(imagePath, Ravg, Gavg, Bavg)
                % Load Image
                I = imread(imagePath);

                % Normalize the Image
                normalized_img = normalize_image(I, Ravg, Gavg, Bavg);

                % Create Mask
                BW_img = createMask(normalized_img);

                % Filter regions and get properties
                [BW_out, properties, features] = filterRegions(BW_img);
            end

            function [BW_out, properties, Tbl] = filterRegions(BW_in)
                % Filter BW image using auto-generated code from imageRegionAnalyzer app.
                BW_out = BW_in;

                % Fill holes in regions.
                BW_out = imfill(BW_out, 'holes');

                % Filter image based on image properties.
                BW_out = bwpropfilt(BW_out, 'Area', [250, 2521]);

                % Get properties.
                properties = regionprops(BW_out, {'Area', 'ConvexArea', 'Eccentricity', 'EquivDiameter', 'Extent', 'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Perimeter', 'Solidity'});

                % Convert stats to a table for easy visualization
                Tbl = struct2table(properties);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.ImportimageButton = uibutton(app.UIFigure, 'push');
            app.ImportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimageButtonPushed, true);
            app.ImportimageButton.Text = 'Import image';
            app.CellCountTextArea = uitextarea(app.UIFigure);
            app.CellCountTextAreaLabel = uilabel(app.UIFigure);
            app.CellCountTextAreaLabel.Text = 'Cell  Count:';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions :';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.Value = {'    '; '      Click on the import image button to browse and select a RBC slide image. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cell_counter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end